classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FullyReversedLoadingLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        CheckFatigueButton         matlab.ui.control.Button
        CyclesValuesEditField_6    matlab.ui.control.NumericEditField
        CyclesValuesEditField_5    matlab.ui.control.NumericEditField
        CyclesValuesEditField_4    matlab.ui.control.NumericEditField
        CyclesLabel                matlab.ui.control.Label
        StressValuesEditField_3    matlab.ui.control.NumericEditField
        StressValuesEditField_2    matlab.ui.control.NumericEditField
        StressValuesLabel          matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        FatigueLifeEstimatorLabel  matlab.ui.control.Label
        StressValuesEditField      matlab.ui.control.NumericEditField
        MaterialsListBox           matlab.ui.control.ListBox
        MaterialsListBoxLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        All_Data %Table with CSV data
    end

    
    methods (Access = public)
        
        function loadCSV(app)
            sn_file = 'S-N curve Data.csv';
            app.All_Data = readtable(sn_file);
            materials = unique(app.All_Data{:, 1});
            app.MaterialsListBox.Items = materials;
        end

        function plotSN(app)
            material = app.MaterialsListBox.Value;
            rows = strcmp(app.All_Data{:, 1}, material);

            stress = app.All_Data{rows, 3};
            Cycles = app.All_Data{rows, 4};

            loglog(app.UIAxes, Cycles, stress, 'ko-', 'LineWidth', 2);
            xlabel(app.UIAxes, 'Number of Cycles');
            ylabel(app.UIAxes, 'Stress (MPa)');
            title(app.UIAxes, ['S-N Curve for ', material]);
            grid(app.UIAxes, 'on');
        end

        function checkFatigue(app)
            if isempty(app.MaterialsListBox.Value)
                msg = "Please select an material to proceed";
                app.Label.Text = msg;
                return;
            end

            material = app.MaterialsListBox.Value;
            data = app.All_Data((strcmp(app.All_Data{:, 1}, material)), :);

            stress1 = app.StressValuesEditField.Value;
            stress2 = app.StressValuesEditField_2.Value;
            stress3 = app.StressValuesEditField_3.Value;

            cycle1 = app.CyclesValuesEditField_4.Value;
            cycle2 = app.CyclesValuesEditField_5.Value;
            cycle3 = app.CyclesValuesEditField_6.Value;

            s_vals = [stress1 stress2 stress3];
            c_vals = [cycle1 cycle2 cycle3];

            D = miners_rule_estimator(s_vals, c_vals, data);

            if D >= 1
                result = sprintf('❌ Fatigue Failure Likely! Damage: %.2f', D*100);
            else
                result = sprintf('✅ Material will survive. Damage: %.2f', D*100);
            end

            app.Label.Text = result;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %Place holder
        end

        % Value changed function: MaterialsListBox
        function MaterialsListBoxValueChanged(app, event)
            plotSN(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            loadCSV(app);
        end

        % Button pushed function: CheckFatigueButton
        function CheckFatigueButtonPushed(app, event)
            checkFatigue(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'S-N Curve')
            xlabel(app.UIAxes, 'Number of Cycles')
            ylabel(app.UIAxes, 'Stress in MPa')
            zlabel(app.UIAxes, 'Z')
            app.MaterialsListBoxLabel = uilabel(app.UIFigure);
            app.MaterialsListBoxLabel.Text = 'Materials';
            app.MaterialsListBox = uilistbox(app.UIFigure);
            app.MaterialsListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialsListBoxValueChanged, true);
            app.MaterialsListBox.Value = {};
            app.StressValuesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StressValuesEditField.Limits = [0 Inf];
            app.StressValuesEditField.AllowEmpty = 'on';
            app.StressValuesEditField.Placeholder = 'Enter ';
            app.StressValuesEditField.Value = [];
            app.FatigueLifeEstimatorLabel = uilabel(app.UIFigure);
            app.FatigueLifeEstimatorLabel.Text = 'Fatigue Life Estimator';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StressValuesLabel = uilabel(app.UIFigure);
            app.StressValuesLabel.Text = 'Stress Values';
            app.StressValuesEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.StressValuesEditField_2.Limits = [0 Inf];
            app.StressValuesEditField_2.AllowEmpty = 'on';
            app.StressValuesEditField_2.Placeholder = 'Enter';
            app.StressValuesEditField_2.Value = [];
            app.StressValuesEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.StressValuesEditField_3.Limits = [0 Inf];
            app.StressValuesEditField_3.AllowEmpty = 'on';
            app.StressValuesEditField_3.Placeholder = 'Enter';
            app.StressValuesEditField_3.Value = [];
            app.CyclesLabel = uilabel(app.UIFigure);
            app.CyclesLabel.Text = 'Cycles';
            app.CyclesValuesEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.CyclesValuesEditField_4.Limits = [0 Inf];
            app.CyclesValuesEditField_4.AllowEmpty = 'on';
            app.CyclesValuesEditField_4.Placeholder = 'Enter';
            app.CyclesValuesEditField_4.Value = [];
            app.CyclesValuesEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.CyclesValuesEditField_5.Limits = [0 Inf];
            app.CyclesValuesEditField_5.AllowEmpty = 'on';
            app.CyclesValuesEditField_5.Placeholder = 'Enter';
            app.CyclesValuesEditField_5.Value = [];
            app.CyclesValuesEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.CyclesValuesEditField_6.Limits = [0 Inf];
            app.CyclesValuesEditField_6.AllowEmpty = 'on';
            app.CyclesValuesEditField_6.Placeholder = 'Enter';
            app.CyclesValuesEditField_6.Value = [];
            app.CheckFatigueButton = uibutton(app.UIFigure, 'push');
            app.CheckFatigueButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFatigueButtonPushed, true);
            app.CheckFatigueButton.Text = 'Check Fatigue';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FullyReversedLoadingLabel = uilabel(app.UIFigure);
            app.FullyReversedLoadingLabel.Text = 'Fully Reversed Loading';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end